classdef BagViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        TestTable_2                matlab.ui.container.Tab
        TestTable                  matlab.ui.control.Table
        TopicTable                 matlab.ui.control.Table
        MessagesLamp               matlab.ui.control.Lamp
        LoadButton                 matlab.ui.control.Button
        TopicEditField             matlab.ui.control.EditField
        PlotViewerTab              matlab.ui.container.Tab
        XSlider                    matlab.ui.control.Slider
        Switch                     matlab.ui.control.Switch
        PositionErrorCheckBox      matlab.ui.control.CheckBox
        EffortErrorCheckBox        matlab.ui.control.CheckBox
        FBKCMDLabel                matlab.ui.control.Label
        TotalEffortCheckBox        matlab.ui.control.CheckBox
        TotalForceCheckBox         matlab.ui.control.CheckBox
        FBKCheckBox                matlab.ui.control.CheckBox
        CMDCheckBox                matlab.ui.control.CheckBox
        INCButton                  matlab.ui.control.Button
        DECButton                  matlab.ui.control.Button
        TimeStepsEditFieldLabel    matlab.ui.control.Label
        TimeStepsEditField         matlab.ui.control.NumericEditField
        EffortAx                   matlab.ui.control.UIAxes
        PositionAx                 matlab.ui.control.UIAxes
        ErrorAx                    matlab.ui.control.UIAxes
        ForceAx                    matlab.ui.control.UIAxes
        OpenDataFolderButton       matlab.ui.control.Button
        CurrentTestEditFieldLabel  matlab.ui.control.Label
        CurrentTestEditField       matlab.ui.control.EditField
        M1CheckBox                 matlab.ui.control.CheckBox
        M2CheckBox                 matlab.ui.control.CheckBox
        M3CheckBox                 matlab.ui.control.CheckBox
        M4CheckBox                 matlab.ui.control.CheckBox
        M5CheckBox                 matlab.ui.control.CheckBox
        M6CheckBox                 matlab.ui.control.CheckBox
        ModuleFilterLabel          matlab.ui.control.Label
        M0CheckBox                 matlab.ui.control.CheckBox
        M7CheckBox                 matlab.ui.control.CheckBox
        M8CheckBox                 matlab.ui.control.CheckBox
        M9CheckBox                 matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        tests = {};
        plots = {};
        idx = 1;
        n_joints = 12;
        max_modules = 10;
        fbk_topic = 'CEELS-joint_state-fbk';
        cmd_topic = 'CEELS-joint_state-cmd';
        error_topic = 'CEELS-joint_state-error';
        force_topic = {'CEELS-joint_state-force0','CEELS-joint_state-force1','CEELS-joint_state-force2',...
            'CEELS-joint_state-force3','CEELS-joint_state-force4','CEELS-joint_state-force5',...
            'CEELS-joint_state-force6', 'CEELS-joint_state-force7'}
        colors = {'#0072BD' '#D95319' '#EDB120' '#7E2F8E' '#77AC30' '#4DBEEE' '#A2142F' '#000000' '#00FF00'};
    end
    
    methods (Access = private)
        
        function read_log(app)
            warning off all
            data_log = readtable('DATA_LOG.xlsx');
            warning on all
            for i = 1:size(data_log,1)
                app.tests(i).test_id = data_log.TESTID{i};
                app.tests(i).test_num = str2num(app.tests(i).test_id(1:3));
                app.tests(i).test_path = ([pwd '\DATA\' app.tests(i).test_id '\']);
                app.tests(i).date = app.tests(i).test_id(5:23);
                app.tests(i).env = data_log.Environment(i);
                app.tests(i).material = data_log.Material(i);
                app.tests(i).propulsion = data_log.PropulsionType(i);
                app.tests(i).test_type = data_log.TestType(i);
                app.tests(i).goal = data_log.PrimaryGoal(i);
                app.tests(i).topics = {};
                app.tests(i).msgs.data = {};
                app.tests(i).msgs.time = [];
                app.tests(i).start_time = 0;
                app.tests(i).end_time = 1;
                app.tests(i).loaded = false;
                app.tests(i).n_joints = app.n_joints;
                
                app.tests(i).fbk.Position = [];
                app.tests(i).fbk.Velocity = [];
                app.tests(i).fbk.Effort = [];
                app.tests(i).fbk.time = [];
                
                app.tests(i).cmd.Position = [];
                app.tests(i).cmd.Velocity = [];
                app.tests(i).cmd.Effort = [];
                app.tests(i).cmd.time = [];
                
                app.tests(i).error.Position = [];
                app.tests(i).error.Velocity = [];
                app.tests(i).error.Effort = [];
                app.tests(i).error.time = [];
                
                app.tests(i).forces.data = [];
                app.tests(i).forces.time = [];
                
                app.plots(i).idx = 0;
                app.plots(i).joint_filter = false([1 app.max_modules]);
                app.plots(i).error_pos = true;
                app.plots(i).error_eff = true;
                app.plots(i).lim.x = [0 1];
                app.plots(i).lim.pos = [0 1];
                app.plots(i).lim.eff = [0 1];
                app.plots(i).lim.error = [0 1];
                app.plots(i).lim.force = [0 1];
            end
            app.TestTable.Data = data_log(:,1:6);
        end
        
        function read_topics(app)
            load([pwd '\CONFIG.mat'],'TOPIC_NAMES')
            for n = 1:length(app.tests)
                files = dir(app.tests(n).test_path);
                for t = 1:length(TOPIC_NAMES)
                    topic_len = length(TOPIC_NAMES{t});
                    for f = 1:length(files)
                        [~,~,ext] = fileparts([files(f).name]);
                        if strcmp(ext,'.mat')
                            topic_name = TOPIC_NAMES{t};
                            topic_file = files(f).name(end-topic_len-3:end-4);
                            if strcmp(topic_name,topic_file)
                                app.tests(n).topics = [app.tests(n).topics; TOPIC_NAMES(t)];
                            end
                        end
                    end
                end
            end
        end
        
        function load_topics(app)
            files = dir(app.tests(app.idx).test_path);
            min_arr = [];
            max_arr = [];
            for f = 1:length(files)
                [~,~,ext] = fileparts([files(f).name]);
                if strcmp(ext,'.mat')
                    for t = 1:length(app.tests(app.idx).topics)
                        file_name = files(f).name;
                        full_name = [app.tests(app.idx).test_id '_' app.tests(app.idx).topics{t} '.mat'];
                        if strcmp(file_name,full_name)
                            app.TopicEditField.Value = app.tests(app.idx).topics{t};
                            app.MessagesLamp.Color = 'yellow';
                            drawnow
                            load([app.tests(app.idx).test_path files(f).name],'msg_arr','time_arr')
                            app.tests(app.idx).msgs(t).data = [msg_arr{:}];
                            app.tests(app.idx).msgs(t).time = time_arr;
                            if ~isempty(time_arr)
                                min_arr(t) = min(time_arr);
                                max_arr(t) = max(time_arr);
                            end
                        end
                    end
                end
            end
            app.tests(app.idx).start_time = min(min_arr);
            app.tests(app.idx).end_time = max(max_arr);
            %% LOAD MESSAGES HERE
            app.TopicEditField.Value = 'Extracting Joint States';
            drawnow
            app.convert_bend_jointstate;
            
            app.TopicEditField.Value = 'Extracting Forces';
            drawnow
            app.convert_forces;
            
            app.tests(app.idx).loaded = true;
            app.TopicEditField.Value = 'Messages Loaded';
            app.MessagesLamp.Color = 'green';
        end
        
        function check_gui(app)
            if app.tests(app.idx).loaded
                app.TopicEditField.Value = 'Messages Loaded';
                app.MessagesLamp.Color = 'green';
            else
                app.TopicEditField.Value = 'Messages Unloaded';
                app.MessagesLamp.Color = 'red';
            end
            
            app.plots(app.idx).error_pos = app.PositionErrorCheckBox.Value;
            app.plots(app.idx).error_eff = app.EffortErrorCheckBox.Value;
            
            app.plots(app.idx).joint_filter(1) = app.M0CheckBox.Value;
            app.plots(app.idx).joint_filter(2) = app.M1CheckBox.Value;
            app.plots(app.idx).joint_filter(3) = app.M2CheckBox.Value;
            app.plots(app.idx).joint_filter(4) = app.M3CheckBox.Value;
            app.plots(app.idx).joint_filter(5) = app.M4CheckBox.Value;
            app.plots(app.idx).joint_filter(6) = app.M5CheckBox.Value;
            app.plots(app.idx).joint_filter(7) = app.M6CheckBox.Value;
            app.plots(app.idx).joint_filter(8) = app.M7CheckBox.Value;
            app.plots(app.idx).joint_filter(9) = app.M8CheckBox.Value;
            app.plots(app.idx).joint_filter(10) = app.M9CheckBox.Value;
        end
        
        function index = get_topic_index(app,topic)
            len = length(app.tests(app.idx).topics);
            index = 0;
            for t = 1:len
                if strcmp(app.tests(app.idx).topics{t},topic)
                    index = t;
                    break;
                end
            end
        end
        
        function convert_bend_jointstate(app)
            fbk_index = app.get_topic_index(app.fbk_topic);
            app.tests(app.idx).n_joints = length(app.tests(app.idx).msgs(fbk_index).data(1).Position);
            i = 1;
            for j = 1:2:app.tests(app.idx).n_joints
                fbk_pos = [app.tests(app.idx).msgs(fbk_index).data.Position];
                fbk_vel = [app.tests(app.idx).msgs(fbk_index).data.Velocity];
                fbk_eff = [app.tests(app.idx).msgs(fbk_index).data.Effort];
                app.tests(app.idx).fbk.Position(i,:) = fbk_pos(j,:);
                app.tests(app.idx).fbk.Velocity(i,:) = fbk_vel(j,:);
                app.tests(app.idx).fbk.Effort(i,:) = fbk_eff(j,:);
                app.tests(app.idx).fbk.time = app.tests(app.idx).msgs(fbk_index).time;
                i = i+1;
            end
            cmd_index = app.get_topic_index(app.cmd_topic);
            i = 1;
            for j = 1:2:app.tests(app.idx).n_joints
                cmd_pos = [app.tests(app.idx).msgs(cmd_index).data.Position];
                cmd_vel = [app.tests(app.idx).msgs(cmd_index).data.Velocity];
                cmd_eff = [app.tests(app.idx).msgs(cmd_index).data.Effort];
                app.tests(app.idx).cmd.Position(i,:) = cmd_pos(j,:);
                app.tests(app.idx).cmd.Velocity(i,:) = cmd_vel(j,:);
                app.tests(app.idx).cmd.Effort(i,:) = cmd_eff(j,:);
                app.tests(app.idx).cmd.time = app.tests(app.idx).msgs(cmd_index).time;
                i = i+1;
            end
            error_index = app.get_topic_index(app.error_topic);
            i = 1;
            for j = 1:2:app.tests(app.idx).n_joints
                error_pos = [app.tests(app.idx).msgs(error_index).data.Position];
                error_vel = [app.tests(app.idx).msgs(error_index).data.Velocity];
                error_eff = [app.tests(app.idx).msgs(error_index).data.Effort];
                app.tests(app.idx).error.Position(i,:) = error_pos(j,:);
                app.tests(app.idx).error.Velocity(i,:) = error_vel(j,:);
                app.tests(app.idx).error.Effort(i,:) = error_eff(j,:);
                app.tests(app.idx).error.time = app.tests(app.idx).msgs(error_index).time;
                i = i+1;
            end
        end
        
        function convert_forces(app)
            force_index = app.get_topic_index(app.force_topic{1});
            if force_index ~= 0
                for f = 1:length(app.force_topic)
                    force_index = app.get_topic_index(app.force_topic{f});
                    force = 9.81/1000*[app.tests(app.idx).msgs(force_index).data.Data];
                    time = app.tests(app.idx).msgs(force_index).time;
                    app.tests(app.idx).forces(f).data = force;
                    app.tests(app.idx).forces(f).time = time;
                end
            end
        end
        
        function init_plots(app)
            app.plot_joint_state;
            app.plot_forces;
            app.init_limits;
            app.init_sliders;
            app.plot_line;
        end
        
        function plot_joint_state(app)
            %% TIME VARIABLES
            fbk_time = app.tests(app.idx).fbk.time - app.tests(app.idx).start_time;
            cmd_time = app.tests(app.idx).cmd.time - app.tests(app.idx).start_time;
            [~, fbk_index] = min(abs(fbk_time - app.plots(app.idx).idx));
            [~, cmd_index] = min(abs(cmd_time - app.plots(app.idx).idx));
            
            cla(app.PositionAx);
            cla(app.EffortAx);
            cla(app.ErrorAx);
            cla(app.ForceAx);
            if app.tests(app.idx).loaded
                ylabel(app.PositionAx,'POSITION (rad)')
                axis(app.PositionAx,'tight')
                grid(app.PositionAx,'on')
                hold(app.PositionAx,'on')
                for j = 1:app.tests(app.idx).n_joints/2
                    if app.plots(app.idx).joint_filter(j)
                        disp(['Joint: ' num2str(j) ' ignored.'])
                    else
                        fbk_pos = round(app.tests(app.idx).fbk.Position(j,fbk_index),4);
                        cmd_pos = round(app.tests(app.idx).cmd.Position(j,cmd_index),4);
                        if app.FBKCheckBox.Value
                            plot(app.PositionAx, fbk_time, app.tests(app.idx).fbk.Position(j,:),'DisplayName',[num2str(j-1) ': ' num2str(fbk_pos,3)],'Color',app.colors{j},'LineStyle','-')
                        end
                        if app.CMDCheckBox.Value
                            plot(app.PositionAx, cmd_time, app.tests(app.idx).cmd.Position(j,:),'DisplayName',[num2str(j-1) ': ' num2str(cmd_pos,3)],'Color',app.colors{j},'LineStyle','--')
                        end
                    end
                end
                hold(app.PositionAx,'off')
                app.PositionAx.YLim
                l = legend(app.PositionAx);
                l.NumColumns = 1;
                l.Location = 'northwest';
                
                ylabel(app.EffortAx,'EFFORT (Nm)')
                axis(app.EffortAx,'tight')
                grid(app.EffortAx,'on')
                hold(app.EffortAx,'on')
                for j = 1:app.tests(app.idx).n_joints/2
                    if app.plots(app.idx).joint_filter(j)
                        disp(['Joint: ' num2str(j) ' ignored.'])
                    else
                        fbk_eff = round(app.tests(app.idx).fbk.Effort(j,fbk_index),4);
                        cmd_eff = round(app.tests(app.idx).cmd.Effort(j,cmd_index),4);
                        if app.FBKCheckBox.Value
                            plot(app.EffortAx, fbk_time, app.tests(app.idx).fbk.Effort(j,:),'DisplayName',[num2str(j-1) ': ' num2str(fbk_eff,3)],'Color',app.colors{j},'LineStyle','-')
                        end
                        if app.CMDCheckBox.Value
                            plot(app.EffortAx, cmd_time, app.tests(app.idx).cmd.Effort(j,:),'DisplayName',[num2str(j-1) ': ' num2str(cmd_eff,3)],'Color',app.colors{j},'LineStyle','--')
                        end
                    end
                end
                if app.TotalEffortCheckBox.Value
                    fbk_eff = sum(app.tests(app.idx).fbk.Effort(:,fbk_index),1);
                    plot(app.EffortAx, fbk_time, sum(app.tests(app.idx).fbk.Effort,1),'DisplayName',['Total: ' num2str(round(fbk_eff,4),3)],'Color',app.colors{end},'LineStyle','-')
                end
                hold(app.EffortAx,'off')
                l = legend(app.EffortAx);
                l.Location = 'northwest';
                
                app.plot_joint_error;
                app.plot_forces;
            end
        end
        
        function plot_joint_error(app)
            cla(app.ErrorAx);
            if app.tests(app.idx).loaded
                ylabel(app.ErrorAx,'ERROR')
                axis(app.ErrorAx,'tight')
                grid(app.ErrorAx,'on')
                hold(app.ErrorAx,'on')
                error_time = app.tests(app.idx).error.time - app.tests(app.idx).start_time;
                for j = 1:app.tests(app.idx).n_joints/2
                    if app.plots(app.idx).joint_filter(j)
                        disp(['Joint: ' num2str(j) ' ignored.'])
                    else
                        if app.plots(app.idx).error_pos
                            plot(app.ErrorAx, error_time, app.tests(app.idx).error.Position(j,:),'DisplayName',['POS:' num2str(j-1)],'Color',app.colors{j},'LineStyle','-')
                        end
                        if app.plots(app.idx).error_eff
                            plot(app.ErrorAx, error_time, app.tests(app.idx).error.Effort(j,:),'DisplayName',['EFF:' num2str(j-1)],'Color',app.colors{j},'LineStyle','--')
                        end
                    end
                end
                hold(app.ErrorAx,'off')
                l = legend(app.ErrorAx);
                l.Location = 'northwest';
            end
        end
        
        function plot_line(app)
            xline(app.PositionAx,app.plots(app.idx).idx,'DisplayName',['T:' num2str(app.plots(app.idx).idx,3)],'Color','red');
            xline(app.EffortAx,app.plots(app.idx).idx,'DisplayName',['T:' num2str(app.plots(app.idx).idx,3)],'Color','red');
            xline(app.ErrorAx,app.plots(app.idx).idx,'DisplayName',['T:' num2str(app.plots(app.idx).idx,3)],'Color','red');
        end
        
        function plot_forces(app)
            cla(app.ForceAx);
            if ~isempty(app.tests(app.idx).forces(1).data) && app.Switch.Value == '0'
                ylabel(app.ForceAx,'FORCE (N)')
                axis(app.ForceAx,'tight')
                grid(app.ForceAx,'on')
                hold(app.ForceAx,'on')
                for f = 1:length(app.force_topic)
                    force_time = app.tests(app.idx).forces(f).time - app.tests(app.idx).start_time;
                    [~, force_index] = min(abs(force_time - app.plots(app.idx).idx));
                    force = round(app.tests(app.idx).forces(f).data(force_index),2);
                    plot(app.ForceAx, force_time, app.tests(app.idx).forces(f).data,'DisplayName',[num2str(f-1) ': ' num2str(force,3)],'Color',app.colors{f},'LineStyle','-')
                end
                if app.TotalForceCheckBox.Value
                    force_time = app.tests(app.idx).forces(f).time - app.tests(app.idx).start_time;
                    [~, force_index] = min(abs(force_time - app.plots(app.idx).idx));
                    total_force = 0;
                    forces = zeros([1 length(app.tests(app.idx).forces(1).data)]);
                    for f = 1:length(app.force_topic)
                        total_force = total_force + app.tests(app.idx).forces(f).data(force_index);
                        tot_len = length(forces);
                        f_len = length(app.tests(app.idx).forces(f).data);
                        if tot_len == f_len
                            forces = forces + app.tests(app.idx).forces(f).data;
                        elseif tot_len < f_len
                            forces = forces + app.tests(app.idx).forces(f).data(1:tot_len);
                        elseif tot_len > f_len
                            forces = forces(1:f_len) + app.tests(app.idx).forces(f).data;
                            force_time = force_time(1:f_len);
                        end
                    end
                    if length(force_time) > length(forces)
                        force_time = force_time(1:length(forces));
                    end
                    plot(app.ForceAx, force_time, forces,'DisplayName',['Total: ' num2str(round(total_force,2),3)],'Color',app.colors{end},'LineStyle','-')
                end
                xline(app.ForceAx,app.plots(app.idx).idx,'DisplayName',['T:' num2str(app.plots(app.idx).idx,3)],'Color','red');
                hold(app.ForceAx,'off')
                app.ForceAx.YLimMode = 'auto';
                app.ForceAx.XLimMode = 'auto';
                app.ForceAx.DataAspectRatioMode = 'auto';
                app.ForceAx.PlotBoxAspectRatio = [2.2922,1,1];
                l = legend(app.ForceAx);
                l.Location = 'northwest';
            elseif ~isempty(app.tests(app.idx).forces(1).data) && app.Switch.Value == '1'
                radius = 1;
                angle = pi/4;
                max_force = 25;
                rot = [cos(pi) -sin(pi); sin(pi) cos(pi)];
                ylabel(app.ForceAx,'FORCE VECTORS (N)')
                axis(app.ForceAx,'tight')
                grid(app.ForceAx,'on')
                hold(app.ForceAx,'on')
                for f = 1:length(app.force_topic)
                    x = -radius * sin(-f * angle);
                    y = -radius * cos(-f * angle);
                    force_time = app.tests(app.idx).forces(f).time - app.tests(app.idx).start_time;
                    [~, force_index] = min(abs(force_time - app.plots(app.idx).idx));
                    force = round(app.tests(app.idx).forces(f).data(force_index),2);
                    force_vec = force/max_force .* rot * [x; y];
                    quiver(app.ForceAx,x,y,force_vec(1),force_vec(2),'DisplayName',[num2str(f-1) ': ' num2str(force,3)],'LineWidth',2,'Color','red');
                end
                theta = 0:pi/100:2*pi;
                plot(app.ForceAx,cos(theta),sin(theta),'Color','red','DisplayName','Circle')
                hold(app.ForceAx,'off')
                zoom = 1.25;
                app.ForceAx.YLim = zoom * [-1 1];
                app.ForceAx.XLim = zoom * [-2.2922 2.2922];
                app.ForceAx.DataAspectRatio = [1 1 1];
                app.ForceAx.PlotBoxAspectRatio = [2.2922,1,1];
                l = legend(app.ForceAx);
                l.Location = 'northwest';
            end
        end
        
        function init_limits(app)
            app.plots(app.idx).lim.x = [0 app.tests(app.idx).end_time-app.tests(app.idx).start_time];
            app.plots(app.idx).lim.pos = app.PositionAx.YLim;
            app.plots(app.idx).lim.eff = app.EffortAx.YLim;
            app.plots(app.idx).lim.error = app.ErrorAx.YLim;
            if app.Switch.Value == '0'&& ~isempty(app.tests(app.idx).forces(1).data)
                app.plots(app.idx).lim.force = app.ForceAx.YLim;
            end
        end
        
        function init_sliders(app)
            app.XSlider.Limits = app.plots(app.idx).lim.x;
            app.XSlider.MajorTicks = app.PositionAx.XTick;
            app.XSlider.MajorTickLabelsMode = 'auto';
            app.XSlider.Value = app.plots(app.idx).idx;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist([pwd '\DATA\BAGS'],'dir')
                app.read_log;
                app.read_topics;
                app.CurrentTestEditField.Value = app.tests(app.idx).test_id;
            else
                app.delete
            end
        end

        % Cell selection callback: TestTable
        function TestTableCellSelection(app, event)
            row = event.Indices(1);
            for i = 1:length(app.tests)
                if strcmp(app.tests(i).test_id, app.TestTable.Data{row,1})
                    app.idx = i;
                    break;
                end
            end
            app.CurrentTestEditField.Value = app.tests(app.idx).test_id;
            app.TopicTable.Data = app.tests(app.idx).topics;
            app.check_gui;
            if app.tests(app.idx).loaded
                app.init_plots;
            end
        end

        % Button pushed function: OpenDataFolderButton
        function OpenDataFolderButtonPushed(app, event)
            winopen(app.tests(app.idx).test_path);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.load_topics;
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: PositionErrorCheckBox
        function PositionErrorCheckBoxValueChanged(app, event)
            app.check_gui;
            app.plot_joint_error;
        end

        % Value changed function: EffortErrorCheckBox
        function EffortErrorCheckBoxValueChanged(app, event)
            app.check_gui;
            app.plot_joint_error;
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            value = app.XSlider.Value;
            app.plots(app.idx).idx = value;
            app.plot_joint_state;
            app.plot_line;
        end

        % Value changed function: M0CheckBox
        function M0CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M1CheckBox
        function M1CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M2CheckBox
        function M2CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M3CheckBox
        function M3CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M4CheckBox
        function M4CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M5CheckBox
        function M5CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: M6CheckBox
        function M6CheckBoxValueChanged(app, event)
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.plot_forces;
        end

        % Value changed function: TotalEffortCheckBox
        function TotalEffortCheckBoxValueChanged(app, event)
            app.init_plots;
        end

        % Value changed function: TotalForceCheckBox
        function TotalForceCheckBoxValueChanged(app, event)
            app.plot_forces;
        end

        % Value changed function: FBKCheckBox
        function FBKCheckBoxValueChanged(app, event)
            if ~app.CMDCheckBox.Value && ~app.FBKCheckBox.Value
                app.CMDCheckBox.Value = true;
            end
            app.check_gui;
            app.init_plots;
        end

        % Value changed function: CMDCheckBox
        function CMDCheckBoxValueChanged(app, event)
            if ~app.CMDCheckBox.Value && ~app.FBKCheckBox.Value
                app.FBKCheckBox.Value = true;
            end
            app.check_gui;
            app.init_plots;
        end

        % Button pushed function: INCButton
        function INCButtonPushed(app, event)
            value = app.TimeStepsEditField.Value;
            bounded = app.plots(app.idx).lim.x(2) > (value + app.plots(app.idx).idx);
            if bounded
                app.plots(app.idx).idx = value + app.plots(app.idx).idx;
            end
            app.check_gui;
            app.init_plots;
        end

        % Button pushed function: DECButton
        function DECButtonPushed(app, event)
            value = app.TimeStepsEditField.Value;
            bounded = app.plots(app.idx).lim.x(1) < (app.plots(app.idx).idx - value);
            if bounded
                app.plots(app.idx).idx = app.plots(app.idx).idx - value;
            end
            app.check_gui;
            app.init_plots;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TestTable_2 = uitab(app.TabGroup);
            app.TestTable_2.Title = 'Test Table';
            app.TestTable = uitable(app.TestTable_2);
            app.TestTable.ColumnName = {'TEST ID'; 'Environment'; 'Material'; 'Propulsion Type'; 'Test Type'; 'Primary Goal'};
            app.TestTable.RowName = {};
            app.TestTable.ColumnSortable = true;
            app.TestTable.CellSelectionCallback = createCallbackFcn(app, @TestTableCellSelection, true);
            app.TopicTable = uitable(app.TestTable_2);
            app.TopicTable.ColumnName = {'TOPIC'};
            app.TopicTable.RowName = {};
            app.TopicTable.ColumnEditable = [false true];
            app.MessagesLamp = uilamp(app.TestTable_2);
            app.MessagesLamp.Tooltip = {''};
            app.LoadButton = uibutton(app.TestTable_2, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.TopicEditField = uieditfield(app.TestTable_2, 'text');
            app.PlotViewerTab = uitab(app.TabGroup);
            app.PlotViewerTab.Title = 'Plot Viewer';
            app.XSlider = uislider(app.PlotViewerTab);
            app.XSlider.MajorTicks = [];
            app.XSlider.MajorTickLabels = {''};
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.MinorTicks = [];
            app.XSlider.Tooltip = {''};
            app.Switch = uiswitch(app.PlotViewerTab, 'slider');
            app.Switch.Items = {'Force Plot', 'Force Vectors'};
            app.Switch.ItemsData = {'0', '1'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '0';
            app.PositionErrorCheckBox = uicheckbox(app.PlotViewerTab);
            app.PositionErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @PositionErrorCheckBoxValueChanged, true);
            app.PositionErrorCheckBox.Text = 'Position Error';
            app.PositionErrorCheckBox.Value = true;
            app.EffortErrorCheckBox = uicheckbox(app.PlotViewerTab);
            app.EffortErrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @EffortErrorCheckBoxValueChanged, true);
            app.EffortErrorCheckBox.Text = 'Effort Error';
            app.EffortErrorCheckBox.Value = true;
            app.FBKCMDLabel = uilabel(app.PlotViewerTab);
            app.FBKCMDLabel.Text = '- = FBK   -- = CMD';
            app.TotalEffortCheckBox = uicheckbox(app.PlotViewerTab);
            app.TotalEffortCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalEffortCheckBoxValueChanged, true);
            app.TotalEffortCheckBox.Text = 'Total Effort';
            app.TotalEffortCheckBox.Value = true;
            app.TotalForceCheckBox = uicheckbox(app.PlotViewerTab);
            app.TotalForceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TotalForceCheckBoxValueChanged, true);
            app.TotalForceCheckBox.Text = 'Total Force';
            app.TotalForceCheckBox.Value = true;
            app.FBKCheckBox = uicheckbox(app.PlotViewerTab);
            app.FBKCheckBox.ValueChangedFcn = createCallbackFcn(app, @FBKCheckBoxValueChanged, true);
            app.FBKCheckBox.Text = 'FBK';
            app.FBKCheckBox.Value = true;
            app.CMDCheckBox = uicheckbox(app.PlotViewerTab);
            app.CMDCheckBox.ValueChangedFcn = createCallbackFcn(app, @CMDCheckBoxValueChanged, true);
            app.CMDCheckBox.Text = 'CMD';
            app.CMDCheckBox.Value = true;
            app.INCButton = uibutton(app.PlotViewerTab, 'push');
            app.INCButton.ButtonPushedFcn = createCallbackFcn(app, @INCButtonPushed, true);
            app.INCButton.Text = 'INC';
            app.DECButton = uibutton(app.PlotViewerTab, 'push');
            app.DECButton.ButtonPushedFcn = createCallbackFcn(app, @DECButtonPushed, true);
            app.DECButton.Text = 'DEC';
            app.TimeStepsEditFieldLabel = uilabel(app.PlotViewerTab);
            app.TimeStepsEditFieldLabel.Text = 'Time Step (s)';
            app.TimeStepsEditField = uieditfield(app.PlotViewerTab, 'numeric');
            app.TimeStepsEditField.Value = 1;
            app.EffortAx = uiaxes(app.PlotViewerTab);
            title(app.EffortAx, 'EFFORT (FBK,CMD)')
            ylabel(app.EffortAx, 'Effort (Nm)')
            app.PositionAx = uiaxes(app.PlotViewerTab);
            title(app.PositionAx, 'POSITION (FBK,CMD)')
            ylabel(app.PositionAx, 'Position (rad)')
            app.ErrorAx = uiaxes(app.PlotViewerTab);
            title(app.ErrorAx, 'ERROR')
            ylabel(app.ErrorAx, 'Y')
            app.ForceAx = uiaxes(app.PlotViewerTab);
            title(app.ForceAx, 'FORCE')
            ylabel(app.ForceAx, 'Y')
            app.OpenDataFolderButton = uibutton(app.UIFigure, 'push');
            app.OpenDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDataFolderButtonPushed, true);
            app.OpenDataFolderButton.Text = 'Open Data Folder';
            app.CurrentTestEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentTestEditFieldLabel.Text = 'Current Test';
            app.CurrentTestEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentTestEditField.Editable = 'off';
            app.M1CheckBox = uicheckbox(app.UIFigure);
            app.M1CheckBox.ValueChangedFcn = createCallbackFcn(app, @M1CheckBoxValueChanged, true);
            app.M1CheckBox.Text = 'M1';
            app.M2CheckBox = uicheckbox(app.UIFigure);
            app.M2CheckBox.ValueChangedFcn = createCallbackFcn(app, @M2CheckBoxValueChanged, true);
            app.M2CheckBox.Text = 'M2';
            app.M3CheckBox = uicheckbox(app.UIFigure);
            app.M3CheckBox.ValueChangedFcn = createCallbackFcn(app, @M3CheckBoxValueChanged, true);
            app.M3CheckBox.Text = 'M3';
            app.M4CheckBox = uicheckbox(app.UIFigure);
            app.M4CheckBox.ValueChangedFcn = createCallbackFcn(app, @M4CheckBoxValueChanged, true);
            app.M4CheckBox.Text = 'M4';
            app.M5CheckBox = uicheckbox(app.UIFigure);
            app.M5CheckBox.ValueChangedFcn = createCallbackFcn(app, @M5CheckBoxValueChanged, true);
            app.M5CheckBox.Text = 'M5';
            app.M5CheckBox.Value = true;
            app.M6CheckBox = uicheckbox(app.UIFigure);
            app.M6CheckBox.ValueChangedFcn = createCallbackFcn(app, @M6CheckBoxValueChanged, true);
            app.M6CheckBox.Text = 'M6';
            app.ModuleFilterLabel = uilabel(app.UIFigure);
            app.ModuleFilterLabel.Text = 'Module Filter:';
            app.M0CheckBox = uicheckbox(app.UIFigure);
            app.M0CheckBox.ValueChangedFcn = createCallbackFcn(app, @M0CheckBoxValueChanged, true);
            app.M0CheckBox.Text = 'M0';
            app.M7CheckBox = uicheckbox(app.UIFigure);
            app.M7CheckBox.Text = 'M7';
            app.M8CheckBox = uicheckbox(app.UIFigure);
            app.M8CheckBox.Text = 'M8';
            app.M9CheckBox = uicheckbox(app.UIFigure);
            app.M9CheckBox.Text = 'M9';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BagViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end